classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DSPPROJECTLabel               matlab.ui.control.Label
        AudioEnhancementLabel         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        loadaudioTab                  matlab.ui.container.Tab
        LoadAudioButton               matlab.ui.control.Button
        speechCheckBox                matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        LoadAudiofileswavformatLabel  matlab.ui.control.Label
        ifuncheckittreatstheaudioasmusicLabel  matlab.ui.control.Label
        WhiteNoiseButton              matlab.ui.control.Button
        TestSignalsLabel              matlab.ui.control.Label
        SineWaveButton                matlab.ui.control.Button
        SweepSignalButton             matlab.ui.control.Button
        ImpulseSignalButton           matlab.ui.control.Button
        PinkNoiseButton               matlab.ui.control.Button
        MultiToneSignalButton         matlab.ui.control.Button
        equalizationTab               matlab.ui.container.Tab
        playButton                    matlab.ui.control.Button
        OrginalaudioLabel             matlab.ui.control.Label
        EqualizationMultistagefiltersLabel  matlab.ui.control.Label
        BassGainSliderLabel           matlab.ui.control.Label
        BassGainSlider                matlab.ui.control.Slider
        MidGainSliderLabel            matlab.ui.control.Label
        MidGainSlider                 matlab.ui.control.Slider
        TrebleGainSliderLabel         matlab.ui.control.Label
        TrebleGainSlider              matlab.ui.control.Slider
        stopButton                    matlab.ui.control.Button
        stopButton_3                  matlab.ui.control.Button
        TimeDomainEqualized           matlab.ui.control.UIAxes
        FrequencySpectrumEqualized    matlab.ui.control.UIAxes
        TimeDomainOriginal            matlab.ui.control.UIAxes
        FrequencySpectrumOriginal     matlab.ui.control.UIAxes
        equalization2Tab              matlab.ui.container.Tab
        playButton_2                  matlab.ui.control.Button
        OrginalaudioLabel_2           matlab.ui.control.Label
        EqualizationParallelfiltersLabel  matlab.ui.control.Label
        stopButton_2                  matlab.ui.control.Button
        BassGainSlider_2Label         matlab.ui.control.Label
        BassGainSlider_2              matlab.ui.control.Slider
        MidGainSlider_2Label          matlab.ui.control.Label
        MidGainSlider_2               matlab.ui.control.Slider
        TrebleGainSlider_2Label       matlab.ui.control.Label
        TrebleGainSlider_2            matlab.ui.control.Slider
        stopButton_4                  matlab.ui.control.Button
        p_TimeDomianEqualized         matlab.ui.control.UIAxes
        p_FrequencySpectrumEqualized  matlab.ui.control.UIAxes
        p_TimeDomainOriginal          matlab.ui.control.UIAxes
        p_FrequencySpectrumOriginal   matlab.ui.control.UIAxes
        finalizationTab               matlab.ui.container.Tab
        SaveEnhancedButton            matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        audio
        fs
        highpass
        bandpass
        lowpass
        m_bassGain = 1
        m_midGain = 1
        m_trebleGain = 1
        output_multiStage
        p_bassGain = 1
        p_midGain = 1
        p_trebleGain = 1
        output_parallel
        player
        isPlaying = false
        

    end
    
    
    methods (Access = private)
        
        function multi_stage(app)
            temp_audio = app.audio;
            
            temp_audio = filter(app.highpass, 1, temp_audio);
            temp_audio = app.m_bassGain * temp_audio;
            
            temp_audio = filter(app.bandpass, 1, temp_audio);
            temp_audio = app.m_midGain * temp_audio;

            temp_audio = filter(app.lowpass, 1, temp_audio);
            temp_audio = app.m_trebleGain * temp_audio;
            
            app.output_multiStage = temp_audio;
            
            if ~isempty(app.player)
                stop(app.player);
                app.player = audioplayer(app.output_multiStage, app.fs);
                if app.isPlaying
                    play(app.player);
                end
            end
        end
        

        function parallel(app)
            bass = filter(app.lowpass, 1, app.audio);
            mid = filter(app.bandpass, 1, app.audio);
            treble = filter(app.highpass, 1, app.audio);
            
            app.output_parallel = app.p_bassGain * bass + ...
                app.p_midGain * mid + ...
                app.p_trebleGain * treble;
                                   
            if ~isempty(app.player)
                stop(app.player);
                app.player = audioplayer(app.output_parallel, app.fs);
                if app.isPlaying
                    play(app.player);
                end
            end
        end
      
    
        function update_equalized_plot(app)
            cla(app.TimeDomainEqualized);
            cla(app.FrequencySpectrumEqualized);
            cla(app.p_TimeDomianEqualized);
            cla(app.p_FrequencySpectrumEqualized);
            
            plot(app.p_TimeDomianEqualized, (1:length(app.output_parallel))/app.fs, app.output_parallel);
            plot(app.TimeDomainEqualized, (1:length(app.output_multiStage))/app.fs, app.output_multiStage);
            
            L = length(app.output_multiStage);
            Y = fft(app.output_multiStage);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = app.fs*(0:(L/2))/L;
            plot(app.FrequencySpectrumEqualized, f, P1);
            
            Lp = length(app.output_parallel);
            Yp = fft(app.output_parallel);
            P2p = abs(Yp/Lp);
            P1p = P2p(1:Lp/2+1);
            P1p(2:end-1) = 2*P1p(2:end-1);
            fp = app.fs*(0:(Lp/2))/Lp;
            plot(app.p_FrequencySpectrumEqualized, fp, P1p);
        end
    
    
        function plot_orginal(app)
            plot(app.TimeDomainOriginal, (1:length(app.audio))/app.fs, app.audio);
            plot(app.p_TimeDomainOriginal, (1:length(app.audio))/app.fs, app.audio);
            L = length(app.audio);
            Y = fft(app.audio);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = app.fs*(0:(L/2))/L;
            plot(app.FrequencySpectrumOriginal, f, P1);
            plot(app.p_FrequencySpectrumOriginal, f, P1);
        end
        
        function fir(app)
            order = 100;
            if app.speechCheckBox.Value
                app.lowpass = fir1(order, 200/(app.fs/2), 'low');
                app.bandpass = fir1(order, [200/(app.fs/2) 3000/(app.fs/2)], 'bandpass');
                app.highpass = fir1(order, 3000/(app.fs/2), 'high');
            else
                app.lowpass = fir1(order, 100/(app.fs/2), 'low');
                app.bandpass = fir1(order, [100/(app.fs/2) 8000/(app.fs/2)], 'bandpass'); 
                app.highpass = fir1(order, 8000/(app.fs/2), 'high');  
            end
        end
    end


    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav', 'Select an Audio File');
            if file
                app.Label.Text = file; 
                [app.audio, app.fs] = audioread(fullfile(path, file));
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
            end
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            if ~isempty(app.audio)
                stop(app.player);
                app.isPlaying = true;
                app.player = audioplayer(app.audio, app.fs);
                play(app.player);
            end
        end

        % Value changed function: BassGainSlider
        function BassGainSliderValueChanged(app, event)
            app.m_bassGain = app.BassGainSlider.Value;
            multi_stage(app);
            update_equalized_plot(app);
        end

        % Value changed function: MidGainSlider
        function MidGainSliderValueChanged(app, event)
            app.m_midGain = app.MidGainSlider.Value;
            multi_stage(app);
            update_equalized_plot(app);
        end

        % Value changed function: TrebleGainSlider
        function TrebleGainSliderValueChanged(app, event)
            app.m_trebleGain = app.TrebleGainSlider.Value;
            multi_stage(app);
            update_equalized_plot(app);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
                app.isPlaying = false;
            end
        end

        % Callback function
        function playButton_3Pushed(app, event)
            if ~isempty(app.output_multiStage)
                stop(app.player);
                app.isPlaying = true;
                app.player = audioplayer(app.output_multiStage, app.fs);
                play(app.player);
            end
        end

        % Button pushed function: SaveEnhancedButton
        function SaveEnhancedButtonPushed(app, event)
            if ~isempty(app.output_multiStage)
                audiowrite('D:\Documents\MATLAB\E_karaoke.wav', app.output_multiStage, app.fs); % Save enhanced audio
                app.Label_2.Text = 'Saved!';
            end
        end

        % Value changed function: BassGainSlider_2
        function BassGainSlider_2ValueChanged(app, event)
            app.p_bassGain = app.BassGainSlider_2.Value;
            parallel(app);
            update_equalized_plot(app);
        end

        % Value changed function: MidGainSlider_2
        function MidGainSlider_2ValueChanged(app, event)
            app.bandpass = app.MidGainSlider_2.Value;
            parallel(app);
            update_equalized_plot(app);
        end

        % Value changed function: TrebleGainSlider_2
        function TrebleGainSlider_2ValueChanged(app, event)
            app.highpass = app.TrebleGainSlider_2.Value;
            parallel(app);
            update_equalized_plot(app);
        end

        % Button pushed function: playButton_2
        function playButton_2Pushed(app, event)
            if ~isempty(app.audio)
                stop(app.player);
                app.isPlaying = true;
                app.player = audioplayer(app.audio, app.fs); 
                play(app.player);
            end
        end

        % Callback function
        function playButton_4Pushed(app, event)
            if ~isempty(app.output_parallel)
                stop(app.player);
                app.isPlaying = true;
                app.player = audioplayer(app.output_parallel, app.fs);
                play(app.player);
            end
        end

        % Button pushed function: stopButton_2
        function stopButton_2Pushed(app, event)
            if ~isempty(app.player)
                stop(app.player); 
                app.isPlaying = false;
            end
        end

        % Callback function
        function BassGainSlider_2ValueChanging(app, event)
            
        end

        % Button pushed function: WhiteNoiseButton
        function WhiteNoiseButtonPushed(app, event)
            s = 44100; 
            duration = 2;
            t = 0:1/s:duration-1/s;
            white_noise = randn(size(t));
            app.audio = white_noise';
            app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end

        % Button pushed function: SineWaveButton
        function SineWaveButtonPushed(app, event)
            s = 44100; 
            duration = 2;
            t = 0:1/s:duration-1/s;
            freq = 1000;
            sine_wave = sin(2 * pi * freq * t);
            app.audio = sine_wave';
            app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end

        % Button pushed function: ImpulseSignalButton
        function ImpulseSignalButtonPushed(app, event)
            s = 44100;
            duration = 2;
            t = 0:1/s:duration-1/s;
            impulse_signal = zeros(size(t));
            impulse_signal(1) = 1; 
            app.audio = impulse_signal';
            app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end

        % Button pushed function: PinkNoiseButton
        function PinkNoiseButtonPushed(app, event)
            s = 44100;
            duration = 2; 
            t = 0:1/s:duration-1/s;
            pink_noise = dsp.ColoredNoise('Color', 'pink', 'SamplesPerFrame', length(t));
            pink_noise = pink_noise()';
            app.audio = pink_noise';
            app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end

        % Button pushed function: SweepSignalButton
        function SweepSignalButtonPushed(app, event)
        s = 44100;
        duration = 2; 
        t = 0:1/s:duration-1/s;
        f0 = 20;
        f1 = 20000; 
        chirp_signal = chirp(t, f0, duration, f1, 'linear');
        app.audio = chirp_signal';
        app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end

        % Button pushed function: MultiToneSignalButton
        function MultiToneSignalButtonPushed(app, event)
        s = 44100; 
        duration = 2; 
        t = 0:1/s:duration-1/s;
        freqs = [100, 1000, 5000]; 
        multi_tone = zeros(size(t));
        for f = freqs
            multi_tone = multi_tone + sin(2 * pi * f * t);
        end
        app.audio = multi_tone';
        app.fs = s;
                plot_orginal(app);
                fir(app);
                multi_stage(app);
                parallel(app);
                app.player = audioplayer(app.output_multiStage, app.fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DSPPROJECTLabel = uilabel(app.UIFigure);
            app.DSPPROJECTLabel.Text = 'DSP PROJECT';
            app.AudioEnhancementLabel = uilabel(app.UIFigure);
            app.AudioEnhancementLabel.Text = 'Audio Enhancement';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loadaudioTab = uitab(app.TabGroup);
            app.loadaudioTab.Title = 'load audio';
            app.LoadAudioButton = uibutton(app.loadaudioTab, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.speechCheckBox = uicheckbox(app.loadaudioTab);
            app.speechCheckBox.Text = 'speech';
            app.speechCheckBox.Value = true;
            app.Label = uilabel(app.loadaudioTab);
            app.Label.Text = '';
            app.LoadAudiofileswavformatLabel = uilabel(app.loadaudioTab);
            app.LoadAudiofileswavformatLabel.Text = 'Load Audio files(.wav format)';
            app.ifuncheckittreatstheaudioasmusicLabel = uilabel(app.loadaudioTab);
            app.ifuncheckittreatstheaudioasmusicLabel.Text = 'if uncheck it treats the audio as music';
            app.WhiteNoiseButton = uibutton(app.loadaudioTab, 'push');
            app.WhiteNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteNoiseButtonPushed, true);
            app.WhiteNoiseButton.Text = 'White Noise';
            app.TestSignalsLabel = uilabel(app.loadaudioTab);
            app.TestSignalsLabel.Text = 'Test Signals';
            app.SineWaveButton = uibutton(app.loadaudioTab, 'push');
            app.SineWaveButton.ButtonPushedFcn = createCallbackFcn(app, @SineWaveButtonPushed, true);
            app.SineWaveButton.Text = 'Sine Wave';
            app.SweepSignalButton = uibutton(app.loadaudioTab, 'push');
            app.SweepSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SweepSignalButtonPushed, true);
            app.SweepSignalButton.Text = 'Sweep Signal';
            app.ImpulseSignalButton = uibutton(app.loadaudioTab, 'push');
            app.ImpulseSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseSignalButtonPushed, true);
            app.ImpulseSignalButton.Text = 'Impulse Signal';
            app.PinkNoiseButton = uibutton(app.loadaudioTab, 'push');
            app.PinkNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PinkNoiseButtonPushed, true);
            app.PinkNoiseButton.Text = 'Pink Noise';
            app.MultiToneSignalButton = uibutton(app.loadaudioTab, 'push');
            app.MultiToneSignalButton.ButtonPushedFcn = createCallbackFcn(app, @MultiToneSignalButtonPushed, true);
            app.MultiToneSignalButton.Text = 'Multi-Tone Signal';
            app.equalizationTab = uitab(app.TabGroup);
            app.equalizationTab.Title = 'equalization';
            app.playButton = uibutton(app.equalizationTab, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.OrginalaudioLabel = uilabel(app.equalizationTab);
            app.OrginalaudioLabel.Text = 'Orginal audio';
            app.EqualizationMultistagefiltersLabel = uilabel(app.equalizationTab);
            app.EqualizationMultistagefiltersLabel.Text = 'Equalization - Multistage filters';
            app.BassGainSliderLabel = uilabel(app.equalizationTab);
            app.BassGainSliderLabel.Text = 'Bass Gain';
            app.BassGainSlider = uislider(app.equalizationTab);
            app.BassGainSlider.Limits = [0 1000];
            app.BassGainSlider.ValueChangedFcn = createCallbackFcn(app, @BassGainSliderValueChanged, true);
            app.BassGainSlider.Value = 5;
            app.MidGainSliderLabel = uilabel(app.equalizationTab);
            app.MidGainSliderLabel.Text = 'Mid Gain';
            app.MidGainSlider = uislider(app.equalizationTab);
            app.MidGainSlider.Limits = [0 1000];
            app.MidGainSlider.ValueChangedFcn = createCallbackFcn(app, @MidGainSliderValueChanged, true);
            app.MidGainSlider.Value = 5;
            app.TrebleGainSliderLabel = uilabel(app.equalizationTab);
            app.TrebleGainSliderLabel.Text = 'Treble Gain';
            app.TrebleGainSlider = uislider(app.equalizationTab);
            app.TrebleGainSlider.Limits = [0 1000];
            app.TrebleGainSlider.ValueChangedFcn = createCallbackFcn(app, @TrebleGainSliderValueChanged, true);
            app.TrebleGainSlider.Value = 5;
            app.stopButton = uibutton(app.equalizationTab, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.stopButton_3 = uibutton(app.equalizationTab, 'push');
            app.stopButton_3.Text = 'stop';
            app.TimeDomainEqualized = uiaxes(app.equalizationTab);
            title(app.TimeDomainEqualized, 'Time-Domain Waveform')
            xlabel(app.TimeDomainEqualized, 'Time (s)')
            ylabel(app.TimeDomainEqualized, 'Amplitude')
            app.FrequencySpectrumEqualized = uiaxes(app.equalizationTab);
            title(app.FrequencySpectrumEqualized, 'Frequency Spectrum')
            xlabel(app.FrequencySpectrumEqualized, 'Frequency')
            ylabel(app.FrequencySpectrumEqualized, 'Magnitude')
            app.TimeDomainOriginal = uiaxes(app.equalizationTab);
            title(app.TimeDomainOriginal, 'Time-Domain Waveform')
            xlabel(app.TimeDomainOriginal, 'Time (s)')
            ylabel(app.TimeDomainOriginal, 'Amplitude')
            app.FrequencySpectrumOriginal = uiaxes(app.equalizationTab);
            title(app.FrequencySpectrumOriginal, 'Frequency Spectrum')
            xlabel(app.FrequencySpectrumOriginal, 'Frequency')
            ylabel(app.FrequencySpectrumOriginal, 'Magnitude')
            app.equalization2Tab = uitab(app.TabGroup);
            app.equalization2Tab.Title = 'equalization 2';
            app.playButton_2 = uibutton(app.equalization2Tab, 'push');
            app.playButton_2.ButtonPushedFcn = createCallbackFcn(app, @playButton_2Pushed, true);
            app.playButton_2.Text = 'play';
            app.OrginalaudioLabel_2 = uilabel(app.equalization2Tab);
            app.OrginalaudioLabel_2.Text = 'Orginal audio';
            app.EqualizationParallelfiltersLabel = uilabel(app.equalization2Tab);
            app.EqualizationParallelfiltersLabel.Text = 'Equalization - Parallel filters';
            app.stopButton_2 = uibutton(app.equalization2Tab, 'push');
            app.stopButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopButton_2Pushed, true);
            app.stopButton_2.Text = 'stop';
            app.BassGainSlider_2Label = uilabel(app.equalization2Tab);
            app.BassGainSlider_2Label.Text = 'Bass Gain';
            app.BassGainSlider_2 = uislider(app.equalization2Tab);
            app.BassGainSlider_2.Limits = [0 1000];
            app.BassGainSlider_2.ValueChangedFcn = createCallbackFcn(app, @BassGainSlider_2ValueChanged, true);
            app.BassGainSlider_2.Value = 5;
            app.MidGainSlider_2Label = uilabel(app.equalization2Tab);
            app.MidGainSlider_2Label.Text = 'Mid Gain';
            app.MidGainSlider_2 = uislider(app.equalization2Tab);
            app.MidGainSlider_2.Limits = [0 1000];
            app.MidGainSlider_2.ValueChangedFcn = createCallbackFcn(app, @MidGainSlider_2ValueChanged, true);
            app.MidGainSlider_2.Value = 5;
            app.TrebleGainSlider_2Label = uilabel(app.equalization2Tab);
            app.TrebleGainSlider_2Label.Text = 'Treble Gain';
            app.TrebleGainSlider_2 = uislider(app.equalization2Tab);
            app.TrebleGainSlider_2.Limits = [0 1000];
            app.TrebleGainSlider_2.ValueChangedFcn = createCallbackFcn(app, @TrebleGainSlider_2ValueChanged, true);
            app.TrebleGainSlider_2.Value = 5;
            app.stopButton_4 = uibutton(app.equalization2Tab, 'push');
            app.stopButton_4.Text = 'stop';
            app.p_TimeDomianEqualized = uiaxes(app.equalization2Tab);
            title(app.p_TimeDomianEqualized, 'Time-Domain Waveform')
            xlabel(app.p_TimeDomianEqualized, 'Time (s)')
            ylabel(app.p_TimeDomianEqualized, 'Amplitude')
            app.p_FrequencySpectrumEqualized = uiaxes(app.equalization2Tab);
            title(app.p_FrequencySpectrumEqualized, 'Frequency Spectrum')
            xlabel(app.p_FrequencySpectrumEqualized, 'Frequency (Hz)')
            ylabel(app.p_FrequencySpectrumEqualized, 'Magnitude')
            app.p_TimeDomainOriginal = uiaxes(app.equalization2Tab);
            title(app.p_TimeDomainOriginal, 'Time-Domain Waveform')
            xlabel(app.p_TimeDomainOriginal, 'Time (s)')
            ylabel(app.p_TimeDomainOriginal, 'Amplitude')
            app.p_FrequencySpectrumOriginal = uiaxes(app.equalization2Tab);
            title(app.p_FrequencySpectrumOriginal, 'Frequency Spectrum')
            xlabel(app.p_FrequencySpectrumOriginal, 'Frequency (Hz)')
            ylabel(app.p_FrequencySpectrumOriginal, 'Magnitude')
            app.finalizationTab = uitab(app.TabGroup);
            app.finalizationTab.Title = 'finalization';
            app.SaveEnhancedButton = uibutton(app.finalizationTab, 'push');
            app.SaveEnhancedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEnhancedButtonPushed, true);
            app.SaveEnhancedButton.Text = 'Save Enhanced!';
            app.Label_2 = uilabel(app.finalizationTab);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end